classdef FourPanelMedicalViewer < matlab.ui.componentcontainer.ComponentContainer
    %FOURPANELMEDICALVIEWER An example UI component for visualizing medical
    %volumes
    %   This component provides a four-panel layout for visualizing medical
    %   imaging data, including orthogonal slice views (transverse,
    %   sagittal, coronal) and a 3D volume rendering view. It also supports
    %   visualization of 4D time-sequence data.
    %   
    %   comp = FOURPANELMEDICALVIEWER(parent) specifies the object in which
    %   to create the Four Panel Medical Viewer.
    %   
    %   comp = FOURPANELMEDICALVIEWER(__, Name, value) specifies properties
    %   using one or more Name,Value pair arguments.
    %   
    %   Name-Value Pairs:
    %   Volume - Medical volume data to visualize, Can have 3D volume or 4D
    %   volume
    %   TimeFrameIndex - Current time frame index for 4D volumes
    %   RenderingStyle - 3D rendering style
    %   Crosshair - Enable or disable the crosshair navigation
    %   
    %   Example 1: Visualize medical volume
    %   
    %   % Read medical volume
    %   mvol = medicalVolume("avg152T1_LR_nifti.nii");
    %   % Create custom UI component and load with data
    %   f = uifigure;
    %   comp = FourPanelMedicalViewer(f,"Position",[0,0,f.Position(3:4)]);
    %   comp.Volume = mvol;
    %   
    %   Example 2: Set rendering style to gradient opacity and enable
    %   crosshair
    %   g = uigridlayout([1,1]);
    %   comp = FourPanelMedicalViewer(g, Volume = mvol,...
    %   RenderingStyle = "GradientOpacity", Crosshair = "on")
    %   
    %   Copyright 2025-2026 The MathWorks, Inc.

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        MainGridLayout    matlab.ui.container.GridLayout
        FourPanelGrid     matlab.ui.container.GridLayout
        Volume3DPanel     matlab.ui.container.Panel
        CoronalPanel      matlab.ui.container.Panel
        CoronalGrid       matlab.ui.container.GridLayout
        CoronalViewer     ImageStackScrubber
        SagittalPanel     matlab.ui.container.Panel
        SagittalGrid      matlab.ui.container.GridLayout
        SagittalViewer    ImageStackScrubber
        TransversePanel   matlab.ui.container.Panel
        TransverseGrid    matlab.ui.container.GridLayout
        TransverseViewer  ImageStackScrubber
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged % Triggered when volume or time frame changes
    end

    properties (Dependent)
        % Volume - Medical volume data to visualize
        Volume         medicalVolume

        % TimeFrameIndex - Current time frame index for 4D volumes (1-based)
        TimeFrameIndex {mustBeNumeric, mustBePositive}

        % RenderingStyle - 3D rendering style
        RenderingStyle string

        % Navigate the slice planes
        Crosshair      {mustBeMember(Crosshair,["on","off"])}
    end

    properties (Access = private, Transient, NonCopyable, UsedInUpdate = false)
        % Internal storage for dependent properties
        Volume_I               medicalVolume
        TimeFrameIndex_I       (1,1) double = 1;

        % IsTimeSeries - Flag indicating if volume is 4D time-series data
        IsTimeSeries           logical = false;

        % Crosshair synchronization flag
        IsUpdatingCrosshair    logical = false;

        % Current crosshair position in voxel coordinates
        CrosshairVoxelPosition (1,3) double = [1, 1, 1];

        % UI components
        Viewer3D               images.ui.graphics.Viewer
        Volume3DRenderer       images.ui.graphics.Volume
    end

    methods
        function [sagittalSliceIdx, coronalSliceIdx, transverseSliceIdx] = getSliceIndices(comp)
            %GETSLICEINDICES Return current slice indices for all three views
            % Returns the current slice index for each orthogonal view
            %
            % Output:
            %   sagittalSliceIdx   - Current sagittal slice index
            %   coronalSliceIdx    - Current coronal slice index
            %   transverseSliceIdx - Current transverse slice index
            sagittalSliceIdx = comp.SagittalViewer.SliceIndex;
            coronalSliceIdx = comp.CoronalViewer.SliceIndex;
            transverseSliceIdx = comp.TransverseViewer.SliceIndex;
        end

        function numFrames = getNumberOfTimeFrames(comp)
            % GETNUMBEROFTIMEFRAMES Get total number of time frames
            if isempty(comp.Volume_I)
                numFrames = 1;
            else
                [~, ~, ~, numFrames] = size(comp.Volume_I.Voxels);
            end
        end

        function tf = isTimeSeriesVolume(comp)
            % ISTIMESERIESVOLUME Check if current volume is 4D time-series
            tf = comp.IsTimeSeries;
        end
    end

    methods
        % Get and set methods for dependent properties
        function set.Volume(comp, volumeData)
            % Set the medical volume and initialize viewers
            comp.Volume_I = volumeData;
            initializeViewers(comp);
        end

        function volumeData = get.Volume(comp)
            % Get the current medical volume
            volumeData = comp.Volume_I;
        end

        function set.TimeFrameIndex(comp, frameIndex)
            % Set current time frame and update all viewers
            comp.TimeFrameIndex_I = round(frameIndex);

            % Update all slice viewers
            if ~isempty(comp.TransverseViewer)
                comp.TransverseViewer.FrameIndex = comp.TimeFrameIndex_I;
            end
            if ~isempty(comp.CoronalViewer)
                comp.CoronalViewer.FrameIndex = comp.TimeFrameIndex_I;
            end
            if ~isempty(comp.SagittalViewer)
                comp.SagittalViewer.FrameIndex = comp.TimeFrameIndex_I;
            end
        end

        function frameIndex = get.TimeFrameIndex(comp)
            % Get current time frame index
            frameIndex = comp.TimeFrameIndex_I;
        end

        function set.RenderingStyle(comp, style)
            % Set 3D rendering style
            if ~isempty(comp.Volume3DRenderer)
                comp.Volume3DRenderer.RenderingStyle = style;
            end
        end

        function style = get.RenderingStyle(comp)
            % Get current 3D rendering style
            if ~isempty(comp.Volume3DRenderer)
                style = comp.Volume3DRenderer.RenderingStyle;
            else
                style = "VolumeRendering";
            end
        end

        function set.Crosshair(comp, value)
            % Set crosshair visibility for all viewers
            if ~isempty(comp.TransverseViewer)
                comp.TransverseViewer.Crosshair = value;
            end
            if ~isempty(comp.SagittalViewer)
                comp.SagittalViewer.Crosshair = value;
            end
            if ~isempty(comp.CoronalViewer)
                comp.CoronalViewer.Crosshair = value;
            end

            % Initialize crosshair positions when turning on
            if value == "on" && ~isempty(comp.Volume_I)
                updateCrosshairPosition(comp);
            end
        end

        function value = get.Crosshair(comp)
            % Get current crosshair state
            value = "off";
            if ~isempty(comp.TransverseViewer)
                value = comp.TransverseViewer.Crosshair;
            end
        end

    end

    methods (Access = private)

        function initializeViewers(comp)
            % INITIALIZEVIEWERS Configure viewers for newly loaded volume

            numDimensions = ndims(comp.Volume_I.Voxels);

            % Check if volume is 4D time-series
            if numDimensions == 4
                comp.IsTimeSeries = true;
            elseif numDimensions == 3
                comp.IsTimeSeries = false;
            else
                error("Volume must be 3D or 4D");
            end

            % Verify volume has affine geometry
            if ~medical.internal.spatial.isAffineEnough(comp.Volume_I.VolumeGeometry)
                error("Volume must be affine");
            end

            % Update all slice viewers
            comp.TransverseViewer.Volume = comp.Volume_I;
            comp.SagittalViewer.Volume = comp.Volume_I;
            comp.CoronalViewer.Volume = comp.Volume_I;

            if ~isVolumeAxesNotAligned(comp.TransverseViewer)
                comp.Volume_I.VolumeGeometry = orient(comp.Volume_I.VolumeGeometry,"LPS+");
                comp.Volume_I = updateOrientation(comp.Volume_I,["left" "posterior" "superior"]);
            end
            % Reset to first time frame
            comp.TimeFrameIndex = 1;
        end

        function updateCrosshairPosition(comp)
            %UPDATECROSSHAIRPOSITION Synchronize crosshair position based on slice indices
            % Converts current slice indices to world coordinates and updates
            % crosshair position in all viewers

            if isempty(comp.Volume_I)
                return;
            end

            % Get current slice indices
            [sagittalSliceIdx, coronalSliceIdx, transverseSliceIdx] = getSliceIndices(comp);

            % Convert to world coordinates
            [crosshairPosition(1),crosshairPosition(2),crosshairPosition(3)] = intrinsicToWorld(comp.Volume.VolumeGeometry, sagittalSliceIdx, coronalSliceIdx, transverseSliceIdx);
            % Update crosshair in all viewers
            comp.TransverseViewer.CrosshairPosition = crosshairPosition;
            comp.CoronalViewer.CrosshairPosition = crosshairPosition;
            comp.SagittalViewer.CrosshairPosition = crosshairPosition;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
            % Create 3D volume viewer
            comp.Viewer3D = viewer3d(comp.Volume3DPanel);
            % Initialize volshow object once during setup for better performance.
            % Subsequently, only the Data property will be updated when the volume changes.
            comp.Volume3DRenderer = volshow(Parent = comp.Viewer3D);

            % Set allowed interaction modes slice viewers: zoom and pan only
            comp.TransverseViewer.AxesInteractions = ["zoom","pan"];
            comp.SagittalViewer.AxesInteractions = ["zoom","pan"];
            comp.CoronalViewer.AxesInteractions = ["zoom","pan"];

            % Set slice plane
            comp.TransverseViewer.SlicePlane = "transverse";
            comp.CoronalViewer.SlicePlane = "coronal";
            comp.SagittalViewer.SlicePlane = "sagittal";
            
            % 3D viewer: zoom, pan, and rotate
            comp.Viewer3D.Interactions = ["zoom","pan","rotate"];
        end

        % Callback function: CoronalViewer, SagittalViewer,
        % TransverseViewer
        function crosshairMoved(comp, event)
            %CROSSHAIRMOVED Handle crosshair movement completion
            % Called when user finishes moving the crosshair
            if comp.IsUpdatingCrosshair || isempty(comp.Volume_I)
                return;
            end

            comp.IsUpdatingCrosshair = true;

            comp.TransverseViewer.CrosshairPosition = event.Position;
            comp.SagittalViewer.CrosshairPosition = event.Position;
            comp.CoronalViewer.CrosshairPosition = event.Position;

            warning('off','medical:medicalref3d:approximateAffine');
            [sagittalIndex,coronalIndex,transverseIndex] = worldToSubscript(comp.Volume.VolumeGeometry, event.Position(1),event.Position(2),event.Position(3));
            warning('on','medical:medicalref3d:approximateAffine');

            volSize = comp.Volume.VolumeGeometry.VolumeSize;
            comp.TransverseViewer.SliceIndex = max(1, min(round(transverseIndex), volSize(3)));
            comp.SagittalViewer.SliceIndex = max(1, min(round(sagittalIndex), volSize(1)));
            comp.CoronalViewer.SliceIndex = max(1, min(round(coronalIndex), volSize(2)));

            comp.IsUpdatingCrosshair = false;
        end

        % Callback function: CoronalViewer, SagittalViewer,
        % TransverseViewer
        function crosshairMoving(comp, event)
            %CROSSHAIRMOVING Handle active crosshair movement
            % Called continuously while user is dragging the crosshair
            if comp.IsUpdatingCrosshair || isempty(comp.Volume_I)
                return;
            end

            comp.IsUpdatingCrosshair = true;

            comp.TransverseViewer.CrosshairPosition = event.Position;
            comp.SagittalViewer.CrosshairPosition = event.Position;
            comp.CoronalViewer.CrosshairPosition = event.Position;

            warning('off','medical:medicalref3d:approximateAffine');
            [sagittalIndex,coronalIndex,transverseIndex] = worldToSubscript(comp.Volume.VolumeGeometry, event.Position(1),event.Position(2),event.Position(3));
            warning('on','medical:medicalref3d:approximateAffine');

            volSize = comp.Volume.VolumeGeometry.VolumeSize;
            comp.TransverseViewer.SliceIndex = max(1, min(round(transverseIndex), volSize(3)));
            comp.SagittalViewer.SliceIndex = max(1, min(round(sagittalIndex), volSize(1)));
            comp.CoronalViewer.SliceIndex = max(1, min(round(coronalIndex), volSize(2)));
            comp.IsUpdatingCrosshair = false;
        end

        % Callback function: CoronalViewer, SagittalViewer,
        % TransverseViewer
        function sliceChanged(comp, event)
            %SLICECHANGED Handle slice index change
            % Updates crosshair position when user scrolls through slices
            if isempty(comp.Volume_I)
                return;
            end
            updateCrosshairPosition(comp);

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            % UPDATE Refresh all viewers with current volume data

            if isempty(comp.Volume_I)
                return;
            end

            warning('off','medical:medicalref3d:oneSliceAffine');
            comp.Volume3DRenderer.Transformation = intrinsicToWorldMapping(comp.Volume_I.VolumeGeometry);
            warning('on','medical:medicalref3d:oneSliceAffine');
            comp.Volume3DRenderer.Data = comp.Volume_I.Voxels(:, :, :, comp.TimeFrameIndex_I);

            % Set orientation axes labels based on patient coordinate system
            patientCoordSystem = comp.Volume_I.VolumeGeometry.PatientCoordinateSystem;
            if ~isempty(patientCoordSystem) && (strlength(patientCoordSystem) == 4)
                % For "LPS+" returns ["" "L" "P" "S" "+" ""]'
                axisLabels = split(patientCoordSystem, '');
                comp.Volume3DRenderer.Parent.Axes.OrientationAxesLabels = [axisLabels(2:4)]';
            end

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 891 717];
            comp.MainGridLayout = uigridlayout(comp);
            comp.FourPanelGrid = uigridlayout(comp.MainGridLayout);
            comp.TransversePanel = uipanel(comp.FourPanelGrid);
            comp.TransversePanel.Title = 'Transverse';
            comp.TransverseGrid = uigridlayout(comp.TransversePanel);
            comp.TransverseViewer = ImageStackScrubber(comp.TransverseGrid);
            comp.TransverseViewer.CrosshairMovedFcn = matlab.apps.createCallbackFcn(comp, @crosshairMoved, true);
            comp.TransverseViewer.CrosshairMovingFcn = matlab.apps.createCallbackFcn(comp, @crosshairMoving, true);
            comp.TransverseViewer.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @sliceChanged, true);
            comp.SagittalPanel = uipanel(comp.FourPanelGrid);
            comp.SagittalPanel.Title = 'Sagittal';
            comp.SagittalGrid = uigridlayout(comp.SagittalPanel);
            comp.SagittalViewer = ImageStackScrubber(comp.SagittalGrid);
            comp.SagittalViewer.CrosshairMovedFcn = matlab.apps.createCallbackFcn(comp, @crosshairMoved, true);
            comp.SagittalViewer.CrosshairMovingFcn = matlab.apps.createCallbackFcn(comp, @crosshairMoving, true);
            comp.SagittalViewer.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @sliceChanged, true);
            comp.CoronalPanel = uipanel(comp.FourPanelGrid);
            comp.CoronalPanel.Title = 'Coronal';
            comp.CoronalGrid = uigridlayout(comp.CoronalPanel);
            comp.CoronalViewer = ImageStackScrubber(comp.CoronalGrid);
            comp.CoronalViewer.CrosshairMovedFcn = matlab.apps.createCallbackFcn(comp, @crosshairMoved, true);
            comp.CoronalViewer.CrosshairMovingFcn = matlab.apps.createCallbackFcn(comp, @crosshairMoving, true);
            comp.CoronalViewer.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @sliceChanged, true);
            comp.Volume3DPanel = uipanel(comp.FourPanelGrid);
            comp.Volume3DPanel.Title = '3D Volume';
            % Execute the startup function
            startupFcn(comp)
        end
    end
end