classdef ImageStackScrubber < matlab.ui.componentcontainer.ComponentContainer
    %IMAGESTACKSCRUBBER Interactive app for viewing medical volumes image
    %slices
    %   The ImageStackScrubber UI component scrolls through medical image
    %   slices. This component provides a 2D viewer with a vertical slider
    %   for navigating through slices of a medical volume in different
    %   anatomical planes (transverse, coronal, or sagittal).
    %   
    %   comp = IMAGESTACKSCRUBBER(parent) specifies the object in which to
    %   create the Image Stack Scrubber.
    %   
    %   comp = IMAGESTACKSCRUBBER(__, Name, value) specifies properties
    %   using one or more Name,Value pair arguments.
    %   
    %   Name-Value Pairs:
    %   Volume     - Medical volume data to visualize, Can have 3D       
    %   volume or 4D volume
    %   SliceIndex - Current slice index
    %   FrameIndex - Current time frame index for 4D volumes
    %   SlicePlane - Anatomical plane for slicing
    %   Crosshair - Enable or disable the crosshair navigation
    %   
    %   Example 1: Visualize medical volume in transverse slice plane
    %   
    %   % Read medical volume
    %   mvol = medicalVolume("avg152T1_LR_nifti.nii");
    %   % Create custom UI component and load with data
    %   f = uifigure;
    %   comp = ImageStackScrubber(f,"Position",[0,0,f.Position(3:4)]);
    %   comp.Volume = mvol;
    %   
    %   Example 2: Set slice index to 10 and enable crosshair
    %   g = uigridlayout([1,1]);
    %   comp = ImageStackScrubber(g, Volume = mvol, ...
    %   SliceIndex = 10, Crosshair = "on");
    %   
    %   Copyright 2026 The MathWorks, Inc.

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Grid    matlab.ui.container.GridLayout
        Slider  matlab.ui.control.Slider
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        CrosshairMoved % Final crosshair position after movement
        CrosshairMoving % Fired when crosshair position changes
        ValueChanged % Fired when the slice index changes via slider interaction
    end

    properties (Dependent)

        % Medical volume to display
        Volume          medicalVolume

        % Current slice index (1-based)
        SliceIndex      {mustBeNumeric, mustBePositive}

        % Anatomical plane for slicing ("transverse", "coronal", or "sagittal")
        SlicePlane      {mustBeTextScalar, mustBeMember(SlicePlane,["transverse","coronal","sagittal"])}

        % Current frame index for 4D volumes (time series)
        FrameIndex      {mustBeNumeric, mustBePositive}

        % Navigate the slice planes
        Crosshair       {mustBeMember(Crosshair,["on","off"])}

    end

    properties (Dependent, UsedInUpdate = false)
        % Current 3D position of the crosshair [x, y, z]
        CrosshairPosition (1,3) double
        % Available interaction modes for the viewer
        AxesInteractions
    end

    properties (Access = private, Transient, NonCopyable, UsedInUpdate = false)

        % Internal storage for dependent properties
        Volume_I        medicalVolume = medicalVolume.empty();  % Current volume data
        Index_I         (1,1) double = 1;                       % Current slice index
        Plane_I         (1,1) string = "transverse";            % Current viewing plane
        FrameIndex_I    (1,1) double = 1;                       % Current frame for 4D data

        % UI components for image display
        Viewer          images.ui.graphics.Viewer
        Image           images.ui.graphics.Image

    end

    methods

        % Get and set methods for dependent properties

        function set.SliceIndex(comp, value)
            comp.Index_I = round(value);
            comp.Slider.Value = comp.Index_I;
        end

        function value = get.SliceIndex(comp)
            value = comp.Index_I;
        end

        function set.SlicePlane(comp, value)
            comp.Plane_I = value;
            resetView(comp);
        end

        function value = get.SlicePlane(comp)
            value = comp.Plane_I;
        end

        function set.Volume(comp, value)
            comp.Volume_I = value;
            resetView(comp);
        end

        function value = get.Volume(comp)
            value = comp.Volume_I;
        end

        function set.FrameIndex(comp, value)
            comp.FrameIndex_I = round(value);
        end

        function value = get.FrameIndex(comp)
            value = comp.FrameIndex_I;
        end

        function value = get.Crosshair(comp)
            % Get information of crosshair enabled or not
            value = comp.Viewer.Axes.Crosshair;
        end

        function set.Crosshair(comp, value)
            % Set 3D rendering style
            comp.Viewer.Axes.Crosshair = value;
        end

        function value = get.CrosshairPosition(comp)
            % Get information of crosshair enabled or not
            value = comp.Viewer.Axes.CrosshairPosition;
        end

        function set.CrosshairPosition(comp, value)
            % Set 3D rendering style
            comp.Viewer.Axes.CrosshairPosition = value;
        end

        function value = get.AxesInteractions(comp)
            % Get information of crosshair enabled or not
            value = comp.Viewer.Interactions;
        end

        function set.AxesInteractions(comp, value)
            % Set 3D rendering style
            comp.Viewer.Interactions = value;
        end

        function value = isVolumeAxesNotAligned(comp)
            % ISVOLUMEAXESNOTALIGNED Check if volume has non-standard orientation
            %   Returns true for oblique or unknown orientations
            value = comp.Volume_I.Orientation == "oblique" || ...
                comp.Volume_I.Orientation == "unknown" || ...
                any(comp.Volume_I.PlaneMapping == "unknown");
        end
    end

    methods (Access = private)

        function setCameraProperties(comp)
            % SETCAMERAPROPERTIES Configure camera orientation for current plane
            %   Sets the camera vector based on the anatomical plane to ensure
            %   correct viewing orientation

            if isempty(comp.Volume_I) || isVolumeAxesNotAligned(comp)
                % Default camera for empty or non-aligned volumes
                comp.Viewer.Mode.Default.CameraVector = [0 0 -1];
                return;
            end

            % Set camera vector based on anatomical plane
            switch lower(comp.Plane_I)
                case "transverse"
                    % View from superior to inferior (top-down)
                    comp.Viewer.Mode.Default.CameraVector = [0 0 -1];
                case "coronal"
                    % View from anterior to posterior (front-back)
                    comp.Viewer.Mode.Default.CameraVector = [0 -1 0];
                case "sagittal"
                    % View from left to right (side view)
                    comp.Viewer.Mode.Default.CameraVector = [1 0 0];
            end
        end

        function addlisteners(comp)
            addlistener(comp.Viewer, "CrosshairMoving", @(src, event) notify(comp,"CrosshairMoving", event));
            addlistener(comp.Viewer, "CrosshairMoved", @(src, event) notify(comp,"CrosshairMoved", event));
        end

        function resetView(comp)
            % RESETVIEW Configure viewer for the current volume
            %   Sets up display range, slider limits, and spatial calibration

            if isempty(comp.Volume_I)
                return;
            end
            setCameraProperties(comp);

            % Get volume dimensions based on orientation
            if isVolumeAxesNotAligned(comp)
                sz = size(comp.Volume.Voxels);
            else
                sz = [comp.Volume.NumCoronalSlices,comp.Volume.NumSagittalSlices,comp.Volume.NumTransverseSlices];
                % Ensure LPS+ orientation for consistent display
                comp.Volume_I.VolumeGeometry = orient(comp.Volume_I.VolumeGeometry,"LPS+");
                comp.Volume_I = updateOrientation(comp.Volume_I,["left" "posterior" "superior"]);
            end

            % Set the display range to span the min and max across the
            % entire volume
            comp.Image.DisplayRangeMode = "data-range";
            [minIntensity, maxIntensity] = bounds(comp.Volume.Voxels,"all");
            comp.Image.DisplayRange = [minIntensity, maxIntensity];

            % Set slider limits based on the plane we are viewing
            switch comp.Plane_I
                case "transverse"
                    comp.Slider.Limits = [1,sz(3)];

                case "coronal"
                    comp.Slider.Limits = [1,sz(1)];

                case "sagittal"
                    comp.Slider.Limits = [1,sz(2)];

            end

            % Restore index to initial value, this will trigger an update
            comp.SliceIndex = 1;

        end

        function pixelSpacing = getPixelSpacing(comp)
            % getPixelSpacing Get pixel spacing for current plane
            %   Returns [x, y] pixel spacing in mm for the current slice plane

            pixelSpacing = ones(1,2);

            if comp.isVolumeAxesNotAligned()
                % For non-aligned volumes, use voxel spacing
                switch lower(comp.Plane_I)
                    case "transverse"
                        pixelSpacing = comp.Volume_I.VoxelSpacing([1 2]);
                    case "sagittal"
                        pixelSpacing = comp.Volume_I.VoxelSpacing([1 3]);
                    case "coronal"
                        pixelSpacing = comp.Volume_I.VoxelSpacing([2 3]);
                end

                pixelSpacing(pixelSpacing == 0) = 1;

            else
                % For aligned volumes, use extractSlice to get spacing

                switch lower(comp.Plane_I)
                    case "transverse"
                        [~,~,pixelSpacing] = extractSlice(comp.Volume_I,1,"transverse");
                    case "sagittal"
                        [~,~,pixelSpacing] = extractSlice(comp.Volume_I,1,"sagittal");
                    case "coronal"
                        [~,~,pixelSpacing] = extractSlice(comp.Volume_I,1,"coronal");
                end

            end

        end

        function tform = createSpatialTransform(~, pixelSpacing)
            % CREATESPATIALTRANSFORM Create affine transform for pixel spacing
            %   Builds a 3D affine transformation matrix for spatial calibration

            transformMatrix = [
                pixelSpacing(2),  0,                0,  0;
                0,                pixelSpacing(1),  0,  0;
                0,                0,                1,  0;
                0,                0,                0,  1
                ];

            tform = affinetform3d(transformMatrix);
        end

        function [im, tform] = extractSlice(comp)
            % EXTRACTSLICE Extract slice and transformation for aligned volumes
            %   Handles proper slice extraction with spatial transformation
            %   for volumes with known anatomical orientation
            %
            %   Output:
            %       im    - 2D image slice
            %       tform - Affine transformation for spatial positioning

            % Find which dimension corresponds to current plane
            boundDim = find(comp.Volume.PlaneMapping == comp.Plane_I);

            % Get the distances and initial position of starting pixel of
            % cube
            d1 = comp.Volume.VolumeGeometry.VoxelDistances{1}(1,:);
            d2 = comp.Volume.VolumeGeometry.VoxelDistances{2}(1,:);
            d3 = comp.Volume.VolumeGeometry.VoxelDistances{3}(1,:);
            pos = comp.Volume.VolumeGeometry.Position(1,:);

            % Transformation matrix of first slice
            T = [[d1' d2' d3' pos'];[0 0 0 1]];
            % Shift transformation from C++ standard of (0,0,0) pixel to
            % MATLAB Standard (1,1,1) pixel
            TShifted = medical.internal.spatial.shift0To1(T);

            % Make transformation matrix according to the camera
            % vector and camera up vector.
            switch boundDim
                case 2 % XZ
                    % Move XY transform to XZ
                    ind = [1 comp.Index_I 1 1];
                    % get the position of requested slice
                    xyzPos = TShifted*ind';

                    T = affinetform3d([[d3' d1' d2' xyzPos(1:3)];[0 0 0 1]]);
                    tform = medical.internal.spatial.shift0To1(T);
                    im = squeeze(comp.Volume.Voxels(:,comp.Index_I,:,:));

                case 1 % YZ
                    % Move XY transform to YZ
                    ind = [comp.Index_I 1 1 1];
                    % get the position of requested slice
                    xyzPos = TShifted*ind';

                    T = affinetform3d([[d3' d2' d1' xyzPos(1:3)];[0 0 0 1]]);
                    tform = medical.internal.spatial.shift0To1(T);
                    im = squeeze(comp.Volume.Voxels(comp.Index_I,:,:,:));

                case 3 % XY
                    ind = [1 1 comp.Index_I 1];
                    % get the position of requested slice
                    xyzPos = TShifted*ind';

                    T = affinetform3d([[d2' d1' d3' xyzPos(1:3)];[0 0 0 1]]);
                    tform = medical.internal.spatial.shift0To1(T);
                    im = squeeze(comp.Volume.Voxels(:,:,comp.Index_I,:));
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(comp)
            % Create Viewer
            comp.Viewer = viewer2d(...
                Parent = comp.Grid,...
                DisplayInfo = "off",...
                Interactions = ["zoom","pan"],...
                DepthPeeling = "off");
            comp.Viewer.Layout.Row = 1;
            comp.Viewer.Layout.Column = 1;

            setCameraProperties(comp);

            % Add an empty image object to the viewer
            comp.Image  = imageshow([],Parent = comp.Viewer);

            addlisteners(comp);
        end

        % Callback function: Slider, Slider
        function sliderChanged(comp, event)

            % Update the slice index as the slider changes
            comp.SliceIndex = round(event.Value);
            notify(comp,"ValueChanged");

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            if isempty(comp.Volume_I)
                return;
            end

            % Extract the appropriate slice based on the selected plane
            if isVolumeAxesNotAligned(comp)
                % Set up spatial calibration (pixel spacing)
                pixelSpacing = getPixelSpacing(comp);
                tform = createSpatialTransform(comp, pixelSpacing);

                % Extract the appropriate slice based on the selected plane
                switch lower(comp.Plane_I)
                    case "transverse"
                        im = squeeze(comp.Volume.Voxels(:,:,comp.Index_I));

                    case "coronal"
                        im = squeeze(comp.Volume.Voxels(:,comp.Index_I,:));

                    case "sagittal"
                        im = squeeze(comp.Volume.Voxels(comp.Index_I,:,:));

                end
            else
                [im, tform] = extractSlice(comp);
            end

            if ndims(comp.Volume.Voxels) > 3
                im = im(:,:,comp.FrameIndex_I);
            end
            % Set the slice as the image data
            comp.Image.Transformation = tform;
            comp.Image.Data = im;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 240];
            comp.Grid = uigridlayout(comp);
            comp.Slider = uislider(comp.Grid);
            comp.Slider.MajorTicks = [];
            comp.Slider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @sliderChanged, true);
            comp.Slider.ValueChangingFcn = matlab.apps.createCallbackFcn(comp, @sliderChanged, true);
            comp.Slider.Step = 1;
            comp.Slider.MinorTicksMode = 'manual';
            % Execute the startup function
            startupFcn(comp)
        end
    end
end